classdef SigViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartRecordButton       matlab.ui.control.StateButton
        CH8Label_2              matlab.ui.control.Label
        CH7Label_2              matlab.ui.control.Label
        CH6Label_2              matlab.ui.control.Label
        CH5Label_2              matlab.ui.control.Label
        CH4Label_2              matlab.ui.control.Label
        CH3Label_2              matlab.ui.control.Label
        CH2Label_2              matlab.ui.control.Label
        CH1Label_2              matlab.ui.control.Label
        Lamp_8                  matlab.ui.control.Lamp
        CH8Label                matlab.ui.control.Label
        Lamp_4                  matlab.ui.control.Lamp
        CH7Label                matlab.ui.control.Label
        Lamp_7                  matlab.ui.control.Lamp
        CH6Label                matlab.ui.control.Label
        Lamp_3                  matlab.ui.control.Lamp
        CH5Label                matlab.ui.control.Label
        Lamp_6                  matlab.ui.control.Lamp
        CH4Label                matlab.ui.control.Label
        Lamp_2                  matlab.ui.control.Lamp
        CH3Label                matlab.ui.control.Label
        Lamp_5                  matlab.ui.control.Lamp
        CH2Label                matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        CH1Label                matlab.ui.control.Label
        RefrenceDropDown        matlab.ui.control.DropDown
        RefrenceDropDownLabel   matlab.ui.control.Label
        NotchCheckBox           matlab.ui.control.CheckBox
        HighPassDropDown        matlab.ui.control.DropDown
        HighPassDropDownLabel   matlab.ui.control.Label
        LowPassDropDown         matlab.ui.control.DropDown
        LowPassDropDownLabel    matlab.ui.control.Label
        AmplitudeDropDown       matlab.ui.control.DropDown
        AmplitudeDropDownLabel  matlab.ui.control.Label
        ImpedanceButton         matlab.ui.control.StateButton
        ConnectButton           matlab.ui.control.StateButton
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialDevice        % 串口设备
        EMG_CHANNEL
        emg_cnt_max
        EMG_bytes           % 记录一帧的字节数据
        emg_cnt_state       % 记录switch函数的状态
        emg_cnt_count       % 每次计数到emg_cnt_max为止
        emg_idx
        EMG_frame           % 数据解析后的数据帧
        result_emg_idx
        EMG_Sequence
        emg_sumchkm
        XL
        line_EMG
        Ifrun               % while是否运行

        Offset              % 通道间幅值偏移量
        offsetA             % 用来构造偏移量的矩阵
        offsetB             % 用来构造偏移量的矩阵

        %%HighPass
        fs = 500;%采样频率
        filt_n=2;%2阶滤波
        fc_high = [0.1 1 5];%截止频率
        b_high
        a_high
        zi_high=[];
        en_highpass=1;
        %%LowPass
        fc_low = [30 40 70];   
        b_low
        a_low
        zi_low=[];
        en_lowpass=1;
        %%notch
        fc_notch = [49 51];  
        b_notch
        a_notch
        zi_notch=[];
        en_notch=1;
        %%标志位
        filterFlag

        %%计算阻抗
        impe_RawData
        impe_Raw_index
        ImpecalculateFlag

        %%文件保存
        filepath
        RecordArray=[];
        recordFlag

    end
    
    properties (Access = public)
        result_emg          % 绘图更新缓冲区，refreshdata处要求public属性
    end
    
    methods (Access = private)
        %%数据接收并解码
        function EEGSig(app)
            while app.Ifrun
                buff=read(app.SerialDevice,660,'uint8');
                for index=1:length(buff)
                    switch(app.emg_cnt_state)
                        case 0
                            if(buff(index)==187) %0xBB
                                app.emg_cnt_state=1;
                            else
                                app.emg_cnt_state=0;
                            end
                        case 1
                            if(buff(index)==170) %0xAA
                                app.emg_cnt_state=2;
                                app.emg_cnt_count=1;
                                app.emg_sumchkm = 0;
                            elseif(buff(index)==187)%排除0xBB BB AA的情况
                                app.emg_cnt_state=1;
                            else
                                app.emg_cnt_state=0;
                            end
                        case 2
                            app.EMG_bytes(1,app.emg_cnt_count) = buff(index);
                            app.emg_cnt_count = app.emg_cnt_count + 1;
                            app.emg_sumchkm = app.emg_sumchkm + buff(index);
                            if(app.emg_cnt_count == app.emg_cnt_max+1)%%判断数据接收是否结束
                                app.emg_cnt_state=3;
                            else
                                app.emg_cnt_state=2;
                            end
                        case 3
                            if(buff(index) == 0)%%检查校验位
                                app.emg_cnt_state=4;
                            else
                                app.emg_cnt_state=0;
                                disp(app.emg_idx)%%校验错误,打印当前帧位置和数据
                            end
                        case 4
                            app.EMG_Sequence(app.emg_idx,1) = buff(index);%%%记录包序号

                            % 原始数据解析%

                            for i=1:9
                                for j=1:8
                                    if(app.EMG_bytes(1,j*3-2+24*(i-1)) > 127)%%%第8位是否为1
                                        app.EMG_frame(i,j) = swapbytes(typecast(uint8([255 app.EMG_bytes((j*3-2+24*(i-1)):j*3+24*(i-1))]),'int32'));
                                    else
                                        app.EMG_frame(i,j) = swapbytes(typecast(uint8([0   app.EMG_bytes((j*3-2+24*(i-1)):j*3+24*(i-1))]),'int32'));
                                    end
                                end
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%%%%%%%%在这里添加存储数据的代码%%%%%%%%%%%
                            %%滤波
                            if app.filterFlag
                                Sigfilter(app);
                            end
                            %%阻抗计算
                            if app.ImpecalculateFlag
                                Impecalculate(app);
                            end
                            %%文件记录
                            if app.recordFlag == 1
                                app.RecordArray = [app.RecordArray;app.EMG_frame];
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            app.emg_idx = app.emg_idx + 1;
                            app.emg_cnt_state=0;  % switch状态切换%
                            % 绘图缓存填充%
                            app.result_emg((app.result_emg_idx-1)*9+1:app.result_emg_idx*9,:)=app.EMG_frame*0.02235174 + app.offsetB*app.Offset;
                            app.result_emg_idx=mod(app.result_emg_idx,278)+1;

                    end
                end
                %%刷新图像
                refreshdata(app.line_EMG,'caller');
                app.XL.Value = (app.result_emg_idx-1)*9;
                drawnow limitrate 
            end

        end
        
        %%滤波处理
        function Sigfilter(app)
            if app.en_highpass ==1
                [app.EMG_frame,app.zi_high] = filter(app.b_high,app.a_high,app.EMG_frame,app.zi_high,2);
            end
            if app.en_lowpass == 1
                [app.EMG_frame,app.zi_low] = filter(app.b_low,app.a_low,app.EMG_frame,app.zi_low,2);
            end
            if app.en_notch == 1
                [app.EMG_frame,app.zi_notch] = filter(app.b_notch,app.a_notch,app.EMG_frame,app.zi_notch,2);
            end            
        end

        %%阻抗计算
        function Impecalculate(app)
            app.impe_RawData(app.impe_Raw_index*9+1:(app.impe_Raw_index+1)*9,:) = app.EMG_frame;
            app.impe_Raw_index = app.impe_Raw_index +1;
            if app.impe_Raw_index == 114
                app.impe_Raw_index = 0;
                iserror = sum(abs(app.impe_RawData)<4000000)/1024;
                ImpeValue = fft(app.impe_RawData,1024);
                tt=max(abs(ImpeValue(63:67,:)));
                q=tt*1000/512*0.02235174/6*pi/4 - 5000;

                for i=1:8
                    if iserror(i)<=0.2
                        q(i)=10000000;
                    end
                end
                value=cell(8,2);
                for i=1:8
                    if q(i)<0
                        value{i} = '0.1K';
                        continue;
                    end
                    if q(i)<1000
                        value{i}  = strcat(string(roundn(q(i)/1000,-1)),'K');
                        continue;
                    end
                    if q(i)<1000000
                        value{i} = strcat(string(roundn(q(i)/1000,-1)),'K');
                        continue;
                    end
                    if q(i)<10000000
                        value{i} = strcat(string(roundn(q(i)/1000000,-1)),'M');
                        continue;
                    else
                        value{i}  = '∞'; 
                    end                            
                end
                for i=1:8
                    if q(i)<30000
                        value{i,2} = 'g';
                        continue;
                    end
                    if q(i)<90000
                        value{i,2} = 'y';
                        continue;
                    else
                        value{i,2} = 'r';
                    end
                end

                app.CH1Label.Text = strcat(string(value{1}),'Ω');
                app.CH2Label.Text = strcat(string(value{2}),'Ω');
                app.CH3Label.Text = strcat(string(value{3}),'Ω');
                app.CH4Label.Text = strcat(string(value{4}),'Ω');
                app.CH5Label.Text = strcat(string(value{5}),'Ω');
                app.CH6Label.Text = strcat(string(value{6}),'Ω');
                app.CH7Label.Text = strcat(string(value{7}),'Ω');
                app.CH8Label.Text = strcat(string(value{8}),'Ω');

                app.Lamp.Color = value{1,2};
                app.Lamp_5.Color = value{2,2};
                app.Lamp_2.Color = value{3,2};
                app.Lamp_6.Color = value{4,2};
                app.Lamp_3.Color = value{5,2};
                app.Lamp_7.Color = value{6,2};
                app.Lamp_4.Color = value{7,2};
                app.Lamp_8.Color = value{8,2};
            end            
        end
        %%关闭阻抗界面的显示
        function ImpeUIVisibleOff(app)
            app.Lamp.Visible="off";
            app.CH1Label_2.Visible="off";
            set(app.CH1Label,'Visible','off');
            app.Lamp_2.Visible="off";
            app.CH2Label_2.Visible="off";
            set(app.CH2Label,'Visible','off');
            app.Lamp_3.Visible="off";
            app.CH3Label_2.Visible="off";
            set(app.CH3Label,'Visible','off');
            app.Lamp_4.Visible="off";
            app.CH4Label_2.Visible="off";
            set(app.CH4Label,'Visible','off');
            app.Lamp_5.Visible="off";
            app.CH5Label_2.Visible="off";
            set(app.CH5Label,'Visible','off');
            app.Lamp_6.Visible="off";
            app.CH6Label_2.Visible="off";
            set(app.CH6Label,'Visible','off');
            app.Lamp_7.Visible="off";
            app.CH7Label_2.Visible="off";
            set(app.CH7Label,'Visible','off');
            app.Lamp_8.Visible="off";
            app.CH8Label_2.Visible="off";
            set(app.CH8Label,'Visible','off');
        end
        %%打开阻抗界面的显示
        function ImpeUIVisibleOn(app)
            app.Lamp.Visible="on";
            app.CH1Label_2.Visible="on";
            set(app.CH1Label,'Visible','on');
            app.Lamp_2.Visible="on";
            app.CH2Label_2.Visible="on";
            set(app.CH2Label,'Visible','on');
            app.Lamp_3.Visible="on";
            app.CH3Label_2.Visible="on";
            set(app.CH3Label,'Visible','on');
            app.Lamp_4.Visible="on";
            app.CH4Label_2.Visible="on";
            set(app.CH4Label,'Visible','on');
            app.Lamp_5.Visible="on";
            app.CH5Label_2.Visible="on";
            set(app.CH5Label,'Visible','on');
            app.Lamp_6.Visible="on";
            app.CH6Label_2.Visible="on";
            set(app.CH6Label,'Visible','on');
            app.Lamp_7.Visible="on";
            app.CH7Label_2.Visible="on";
            set(app.CH7Label,'Visible','on');
            app.Lamp_8.Visible="on";
            app.CH8Label_2.Visible="on";
            set(app.CH8Label,'Visible','on');
        end
        %%关闭坐标轴的显示
        function AxesUIVisibleOff(app)
            app.UIAxes.Visible = 'off';
            for i=1:8
                set(app.line_EMG{i},'Visible','off');
            end              
            set(app.XL,'Visible','off');             
        end
        %%打开坐标轴的显示
        function AxesUIVisibleOn(app)
            app.UIAxes.Visible = 'on';
            for i=1:8
                set(app.line_EMG{i},'Visible','on');
            end              
            set(app.XL,'Visible','on');             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% APP初始化
            app.EMG_CHANNEL=8;
            app.emg_cnt_max=app.EMG_CHANNEL*3*9;
            app.EMG_bytes=zeros(1,app.emg_cnt_max);%%%记录一帧的字节数据
            app.emg_cnt_state=0;%%%记录switch函数的状态
            app.emg_cnt_count=0;%%%每次计数到emg_cnt_max为止
            app.emg_idx=1;
            app.EMG_frame=zeros(9,app.EMG_CHANNEL);%%%数据解析后的数据帧
            app.result_emg=zeros(2502,app.EMG_CHANNEL) - 1;%%%绘图更新缓冲区，减1为了初始时不显示y=0的横线
            app.result_emg_idx=1;
            
            app.Offset=200;
            app.offsetA=[0.5 1.5 2.5 3.5 4.5 5.5 6.5 7.5];
            app.offsetB=zeros(9,8);
            app.offsetB = app.offsetB + app.offsetA;

            [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(1)/(app.fs/2),'high');
            [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(1)/(app.fs/2),'low');
            [app.b_notch,app.a_notch] = butter(2,app.fc_notch/(app.fs/2),'stop');

            app.impe_RawData=zeros(1026,8);
            app.impe_Raw_index = 0;
            
            ImpeUIVisibleOff(app);%关闭阻抗显示


            %设置图的数据源
            T=['app.result_emg(:,  )'];
            for Z=1:8
                app.line_EMG{Z}=plot(app.UIAxes,app.result_emg(:,Z));
                if Z<10
                    T(end-1)=string(Z);
                else
                    T(end-2:end-1)=string(Z);
                end
                set(app.line_EMG{Z},'YDataSource',T);  % 设置y轴数据来源 data1
                hold(app.UIAxes,"on")      % 为了让多条图线在一个图中显示
            end

            %设置垂直线
            app.XL = xline(app.UIAxes,0);
            
            app.Ifrun = false;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            %% 串口连接按钮按下事件
            value = app.ConnectButton.Value;
            if value == 1
                delete(instrfindall);
                scom = 'COM4'; %name of serial
                Baudrate = 921600; %serial baudrate, should be same as the mcu setting
                try
                    app.SerialDevice = serialport(scom,Baudrate);
                    app.ConnectButton.Text='Disconnect';
                    app.ConnectButton.BackgroundColor=[0.07 0.62 1.00];
                    disp('串口打开成功');
                catch
                    disp('串口打开失败');
                    delete(app.SerialDevice);
                end
            else
                delete(app.SerialDevice);
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [0.8 0.8 0.8];
            end
        end

        % Value changed function: ImpedanceButton
        function ImpedanceButtonValueChanged(app, event)
            %% 运行模式EEG/IMPE按钮按下事件
            value = app.ImpedanceButton.Value;
            if value == 1
                write(app.SerialDevice,'Z',"uint8");
                app.ImpedanceButton.Text='EEG';
                ImpeUIVisibleOn(app);
                AxesUIVisibleOff(app);
                app.Ifrun = true; 
                app.filterFlag = 0;
                app.ImpecalculateFlag = 1;
                EEGSig(app);                
            else
                write(app.SerialDevice,'T',"uint8");
                app.ImpedanceButton.Text='Impedance';
                ImpeUIVisibleOff(app);
                AxesUIVisibleOn(app);
                app.Ifrun = true;   
                app.filterFlag = 1;
                app.ImpecalculateFlag = 0;
                EEGSig(app);
            end            
        end

        % Value changed function: AmplitudeDropDown
        function AmplitudeDropDownValueChanged(app, event)
            %% 幅值刻度设置
            % 从头开始绘图
            app.emg_cnt_state=0;%%%记录switch函数的状态
            app.emg_cnt_count=0;%%%每次计数到emg_cnt_max为止
            app.emg_idx=1;
            app.EMG_frame=zeros(9,app.EMG_CHANNEL);%%%数据解析后的数据帧
            app.result_emg=zeros(2502,app.EMG_CHANNEL) - 1;%%%绘图更新缓冲区，减1为了初始时不显示y=0的横线
            app.result_emg_idx=1;

            value = app.AmplitudeDropDown.Value;
            switch value
                case '10 uV'
                    app.Offset = 20;
                    app.UIAxes.YLim=[0 160];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
                case '50 uV'
                    app.Offset = 100;
                    app.UIAxes.YLim=[0 800];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*5;
                case '100 uV'
                    app.Offset = 200;
                    app.UIAxes.YLim=[0 1600];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*10;
                case '200 uV'
                    app.Offset = 400;
                    app.UIAxes.YLim=[0 3200];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*20;
                case '400 uV'
                    app.Offset = 800;
                    app.UIAxes.YLim=[0 6400];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*40;
                case '1 mV'
                    app.Offset = 2000;
                    app.UIAxes.YLim=[0 16000];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*100;
                case '3 mV'
                    app.Offset = 6000;
                    app.UIAxes.YLim=[0 48000];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*300;
            end
        end

        % Value changed function: HighPassDropDown
        function HighPassDropDownValueChanged(app, event)
            value = app.HighPassDropDown.Value;
            switch value
                case 'None'
                    app.en_highpass = 0;
                case '0.1 Hz'
                    app.en_highpass = 1;
                    [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(1)/(app.fs/2),'high');
                case '1 Hz'
                    app.en_highpass = 1;
                    [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(2)/(app.fs/2),'high');
                case '5 Hz'
                    app.en_highpass = 1;
                    [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(3)/(app.fs/2),'high');
            end
        end

        % Value changed function: LowPassDropDown
        function LowPassDropDownValueChanged(app, event)
            value = app.LowPassDropDown.Value;
            switch value
                case 'None'
                    app.en_lowpass = 0;
                case '30 Hz'
                    app.en_lowpass = 1;
                    [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(1)/(app.fs/2),'low');
                case '40 Hz'
                    app.en_lowpass = 1;
                    [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(2)/(app.fs/2),'low');
                case '70 Hz'
                    app.en_lowpass = 1;
                    [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(3)/(app.fs/2),'low');
            end
        end

        % Value changed function: NotchCheckBox
        function NotchCheckBoxValueChanged(app, event)
            value = app.NotchCheckBox.Value;
            if value == 1
                app.en_notch=1;
            else
                app.en_notch=0;
            end
        end

        % Callback function
        function RecordButtonPushed(app, event)
            [filename,path] = uiputfile('*.mat','Save file name','ufilename');
            app.filepath=strcat(path,filename);
            a=app.EMG_Sequence;
            save(app.filepath,'a');
        end

        % Value changed function: StartRecordButton
        function StartRecordButtonValueChanged(app, event)
            value = app.StartRecordButton.Value;
            if value == 1
                [filename,path] = uiputfile('*.mat','Save file name','ufilename');
                app.filepath=strcat(path,filename);
                app.StartRecordButton.Text='Stop Recording';
                app.StartRecordButton.BackgroundColor = [0.07 0.62 1.00];
                app.recordFlag = 1;
            else
                a=app.RecordArray;
                save(app.filepath,'a');
                app.StartRecordButton.Text='Start Record';
                app.StartRecordButton.BackgroundColor = [0.8 0.8 0.8];
                app.recordFlag = 0;
                app.RecordArray = [];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 2502];
            app.UIAxes.YLim = [0 1600];
            app.UIAxes.YTick = [100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500];
            app.UIAxes.YTickLabel = {'CH8'; ''; 'CH7'; ''; 'CH6'; ''; 'CH5'; ''; 'CH4'; ''; 'CH3'; ''; 'CH2'; ''; 'CH1'};
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ImpedanceButton = uibutton(app.UIFigure, 'state');
            app.ImpedanceButton.ValueChangedFcn = createCallbackFcn(app, @ImpedanceButtonValueChanged, true);
            app.ImpedanceButton.Text = 'Impedance';
            app.AmplitudeDropDownLabel = uilabel(app.UIFigure);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.UIFigure);
            app.AmplitudeDropDown.Items = {'10 uV', '50 uV', '100 uV', '200 uV', '400 uV', '1 mV', '3 mV'};
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeDropDownValueChanged, true);
            app.AmplitudeDropDown.Value = '100 uV';
            app.LowPassDropDownLabel = uilabel(app.UIFigure);
            app.LowPassDropDownLabel.Text = 'LowPass';
            app.LowPassDropDown = uidropdown(app.UIFigure);
            app.LowPassDropDown.Items = {'None', '30 Hz', '40 Hz', '70 Hz'};
            app.LowPassDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassDropDownValueChanged, true);
            app.LowPassDropDown.Value = '30 Hz';
            app.HighPassDropDownLabel = uilabel(app.UIFigure);
            app.HighPassDropDownLabel.Text = 'HighPass';
            app.HighPassDropDown = uidropdown(app.UIFigure);
            app.HighPassDropDown.Items = {'None', '0.1 Hz', '1 Hz', '5 Hz'};
            app.HighPassDropDown.ValueChangedFcn = createCallbackFcn(app, @HighPassDropDownValueChanged, true);
            app.HighPassDropDown.Value = '0.1 Hz';
            app.NotchCheckBox = uicheckbox(app.UIFigure);
            app.NotchCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchCheckBoxValueChanged, true);
            app.NotchCheckBox.Text = 'Notch';
            app.NotchCheckBox.Value = true;
            app.RefrenceDropDownLabel = uilabel(app.UIFigure);
            app.RefrenceDropDownLabel.Text = 'Refrence';
            app.RefrenceDropDown = uidropdown(app.UIFigure);
            app.RefrenceDropDown.Items = {'None', 'Average', 'CH1', 'CH2', 'CH3', 'CH4', 'CH5', 'CH6', 'CH7', 'CH8'};
            app.RefrenceDropDown.Value = 'None';
            app.CH1Label = uilabel(app.UIFigure);
            app.CH1Label.Text = 'Ω';
            app.Lamp = uilamp(app.UIFigure);
            app.CH2Label = uilabel(app.UIFigure);
            app.CH2Label.Text = 'Ω';
            app.Lamp_5 = uilamp(app.UIFigure);
            app.CH3Label = uilabel(app.UIFigure);
            app.CH3Label.Text = 'Ω';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.CH4Label = uilabel(app.UIFigure);
            app.CH4Label.Text = 'Ω';
            app.Lamp_6 = uilamp(app.UIFigure);
            app.CH5Label = uilabel(app.UIFigure);
            app.CH5Label.Text = 'Ω';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.CH6Label = uilabel(app.UIFigure);
            app.CH6Label.Text = 'Ω';
            app.Lamp_7 = uilamp(app.UIFigure);
            app.CH7Label = uilabel(app.UIFigure);
            app.CH7Label.Text = 'Ω';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.CH8Label = uilabel(app.UIFigure);
            app.CH8Label.Text = 'Ω';
            app.Lamp_8 = uilamp(app.UIFigure);
            app.CH1Label_2 = uilabel(app.UIFigure);
            app.CH1Label_2.Text = 'CH1:';
            app.CH2Label_2 = uilabel(app.UIFigure);
            app.CH2Label_2.Text = 'CH2:';
            app.CH3Label_2 = uilabel(app.UIFigure);
            app.CH3Label_2.Text = 'CH3:';
            app.CH4Label_2 = uilabel(app.UIFigure);
            app.CH4Label_2.Text = 'CH4:';
            app.CH5Label_2 = uilabel(app.UIFigure);
            app.CH5Label_2.Text = 'CH5:';
            app.CH6Label_2 = uilabel(app.UIFigure);
            app.CH6Label_2.Text = 'CH6:';
            app.CH7Label_2 = uilabel(app.UIFigure);
            app.CH7Label_2.Text = 'CH7:';
            app.CH8Label_2 = uilabel(app.UIFigure);
            app.CH8Label_2.Text = 'CH8:';
            app.StartRecordButton = uibutton(app.UIFigure, 'state');
            app.StartRecordButton.ValueChangedFcn = createCallbackFcn(app, @StartRecordButtonValueChanged, true);
            app.StartRecordButton.Text = 'Start Record';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SigViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end